classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BackgroundPictureButton  matlab.ui.control.Button
        WashersPictureButton     matlab.ui.control.Button
        FindWashersButton        matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        UITable_2                matlab.ui.control.Table
        StartProcessButton       matlab.ui.control.Button
        TimesEditFieldLabel      matlab.ui.control.Label
        TimesEditField           matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.UserData = gameState;
            app.UIFigure.UserData.cam = webcam('HP USB Webcam');
            app.UITable.ColumnName = {'Degree', 'Color'};
            app.UITable_2.ColumnName = {'Degree', 'Color', 'Weight [g]'};
            app.UITable.Data = [{1, 2, 3, 4, 5, 6, 7, 8, 9, 10}', {'','','','','','','','','',''}'];
            app.UITable_2.Data = [{1, 2, 3, 4, 5, 6, 7, 8, 9, 10}', ...
                                  {'','','','','','','','','',''}', ...
                                  {'','','','','','','','','',''}'];
            imshow('background.jpg', 'Parent', app.UIAxes);
        end

        % Button pushed function: BackgroundPictureButton
        function BackgroundPictureButtonPushed(app, event)
            app.UIFigure.UserData.background = getBackgroundImage(app.UIFigure.UserData.cam);
            app.UITable.Data = [{1, 2, 3, 4, 5, 6, 7, 8, 9, 10}', {'','','','','','','','','',''}'];
            app.UITable_2.Data = [{1, 2, 3, 4, 5, 6, 7, 8, 9, 10}', {'','','','','','','','','',''}', {'','','','','','','','','',''}'];
            %app.UIFigure.UserData.background = imread('background.jpg');
            %imshow(app.UIFigure.UserData.background, 'Parent', app.UIAxes);       
        end

        % Button pushed function: FindWashersButton
        function FindWashersButtonPushed(app, event)
            %Turns background and snapshot picture to Gray-Scale
            app.UIFigure.UserData.backgroundG = rgb2gray(app.UIFigure.UserData.background);
            app.UIFigure.UserData.snapShotG = rgb2gray(app.UIFigure.UserData.snapShot);
            
            %Takes the difference between converted Gray-Scale images
            app.UIFigure.UserData.difference = app.UIFigure.UserData.backgroundG - app.UIFigure.UserData.snapShotG;
            
            %Takes the difference image and converts it to a Binary Image
            app.UIFigure.UserData.diffI = im2bw(app.UIFigure.UserData.difference, 0.15);
            
            %Erodes Binary image
            se = strel('disk', 3);
            app.UIFigure.UserData.erode = imerode(app.UIFigure.UserData.diffI, se);
            se2 = strel('disk', 10);
       
            %Dilates Eroded image
            app.UIFigure.UserData.dilute = imdilate(app.UIFigure.UserData.erode, se2);
            
            %Fills holes in image
            app.UIFigure.UserData.fillImage = imfill(app.UIFigure.UserData.dilute, 'holes');
            
            %Obtains Centroid of each washer
            app.UIFigure.UserData.props = regionprops(app.UIFigure.UserData.fillImage);
            
            %Labels each Washer
            %centroidNumber(app.UIFigure.UserData.snapShot, app.UIFigure.UserData.props);
            
            %Determines the Color of each washer
            app.UIFigure.UserData.allWashers = getAllWashers(app.UIFigure.UserData.snapShot, app.UIFigure.UserData.props);
            
            
            %Determines the degree of each Washer
            app.UIFigure.UserData.degrees = getWashersDegrees(app.UIFigure.UserData.props);
            app.UIFigure.UserData.wellLocations = [-27.5, -60.5, -92.5, -120, -156, 13.50, 43.5, 87.5, 135, 163];
            
            %Display Color of Washers Detected on Table
            app.UIFigure.UserData.degreeAndColor = sortDegreesAndColors(app.UIFigure.UserData.wellLocations, app.UIFigure.UserData.allWashers, app.UIFigure.UserData.degrees);
            app.UITable.Data = [{1, 2, 3, 4, 5, 6, 7, 8, 9, 10}'...
                ,{char(app.UIFigure.UserData.degreeAndColor{1}{2}), char(app.UIFigure.UserData.degreeAndColor{2}{2}) ...
                ,char(app.UIFigure.UserData.degreeAndColor{3}{2}),char(app.UIFigure.UserData.degreeAndColor{4}{2}) ...
                ,char(app.UIFigure.UserData.degreeAndColor{5}{2}),char(app.UIFigure.UserData.degreeAndColor{6}{2}) ...
                ,char(app.UIFigure.UserData.degreeAndColor{7}{2}),char(app.UIFigure.UserData.degreeAndColor{8}{2})...
                ,char(app.UIFigure.UserData.degreeAndColor{9}{2}),char(app.UIFigure.UserData.degreeAndColor{10}{2})}'];
            app.UITable_2.ColumnEditable = [false true true];
            
            %Start the Target PC
            app.UIFigure.UserData.tg = setTargetPC();        
        end

        % Button pushed function: StartProcessButton
        function StartProcessButtonPushed(app, event)
            %Turn on LED
            app.UIFigure.UserData.tg.setparam(app.UIFigure.UserData.tg.getparamid('LED', 'Value'), 1);
            
            %Start Timer
            t = timer('Name','TimerCounter', 'Period',.01, 'StartDelay',.01, 'TasksToExecute',inf, 'ExecutionMode','fixedSpacing', 'TimerFcn',@tmr_tmrfcn);  
            start(t);
            time = tic;
            
            %Obtain input from User from Table 2
            app.UIFigure.UserData.degreeColorWeight = app.UITable_2.Data;
            
            %Sort the Washers Algorithm
            sortWashers2(app.UIFigure.UserData.degreeAndColor, app.UIFigure.UserData.degreeColorWeight, app.UIFigure.UserData.tg);
            
            %Turn off LED
            app.UIFigure.UserData.tg.setparam(app.UIFigure.UserData.tg.getparamid('LED', 'Value'), 0);
            
            %Stop Target PC
            app.UIFigure.UserData.tg.stop;
            stop(t);
            delete(t);
            clear t;  
            function [] = tmr_tmrfcn(varargin) 
                app.TimesEditField.Value = toc(time);
            end
        end

        % Button pushed function: WashersPictureButton
        function WashersPictureButtonPushed(app, event)
            
            app.UIFigure.UserData.snapShot = getSnapshotImage(app.UIFigure.UserData.cam);
            %app.UIFigure.UserData.snapShot = imread('snapShot.jpg');
            %imshow(app.UIFigure.UserData.snapShot, 'Parent', app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BackgroundPictureButton = uibutton(app.UIFigure, 'push');
            app.BackgroundPictureButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundPictureButtonPushed, true);
            app.BackgroundPictureButton.Text = 'Background Picture';
            app.WashersPictureButton = uibutton(app.UIFigure, 'push');
            app.WashersPictureButton.ButtonPushedFcn = createCallbackFcn(app, @WashersPictureButtonPushed, true);
            app.WashersPictureButton.Text = 'Washers Picture';
            app.FindWashersButton = uibutton(app.UIFigure, 'push');
            app.FindWashersButton.ButtonPushedFcn = createCallbackFcn(app, @FindWashersButtonPushed, true);
            app.FindWashersButton.Text = 'Find Washers';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable_2.RowName = {};
            app.StartProcessButton = uibutton(app.UIFigure, 'push');
            app.StartProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessButtonPushed, true);
            app.StartProcessButton.Text = 'Start Process';
            app.TimesEditFieldLabel = uilabel(app.UIFigure);
            app.TimesEditFieldLabel.Text = 'Time [s]';
            app.TimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimesEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end