classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BackgroundPictureButton  matlab.ui.control.Button
        WashersPictureButton     matlab.ui.control.Button
        FindWashersButton        matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        UITable_2                matlab.ui.control.Table
        StartProcessButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.UserData = gameState;
            %app.UIFigure.UserData.cam = webcam('HP USB Webcam');
            app.UITable.ColumnName = {'Degree', 'Color'};
            app.UITable_2.ColumnName = {'Degree', 'Color'};
            app.UITable.Data = [{-25, -50, -70, -100, -160, 25, 50, 70, 100, 160}', {'','','','','','','','','',''}'];
            app.UITable_2.Data = [{-25, -50, -70, -100, -160, 25, 50, 70, 100, 160}', {'','','','','','','','','',''}'];
        end

        % Button pushed function: BackgroundPictureButton
        function BackgroundPictureButtonPushed(app, event)
            %app.UIFigure.UserData.background = getBackgroundImage(app.UIFigure.UserData.cam);
            app.UIFigure.UserData.background = imread('back1.jpg');
            imshow(app.UIFigure.UserData.background);
        end

        % Button pushed function: FindWashersButton
        function FindWashersButtonPushed(app, event)
            app.UIFigure.UserData.backgroundG = rgb2gray(app.UIFigure.UserData.background);
            app.UIFigure.UserData.snapShotG = rgb2gray(app.UIFigure.UserData.snapShot);
            app.UIFigure.UserData.difference = app.UIFigure.UserData.backgroundG - app.UIFigure.UserData.snapShotG;
            app.UIFigure.UserData.diffI = im2bw(app.UIFigure.UserData.difference, 0.15);
            se = strel('disk', 3);
            app.UIFigure.UserData.erode = imerode(app.UIFigure.UserData.diffI, se);
            se2 = strel('disk', 10);
            app.UIFigure.UserData.dilute = imdilate(app.UIFigure.UserData.erode, se2);
            app.UIFigure.UserData.fillImage = imfill(app.UIFigure.UserData.dilute, 'holes');
            app.UIFigure.UserData.props = regionprops(app.UIFigure.UserData.fillImage);
            app.UIFigure.UserData.allWashers = getAllWashers(app.UIFigure.UserData.snapShot, app.UIFigure.UserData.props);
            if (app.UIFigure.UserData.allWashers(1) == 0)
                clear all;
                close all;
                clc;
                imaqreset;
                fprintf('No Washers Detected\n');
                fprintf('Program will be terminated\n');
            else
                app.UIFigure.UserData.degrees = getWashersDegrees(app.UIFigure.UserData.props);
%                 app.UIFigure.UserData.tg = setTargetPC();
%                 rotateMotorAll(app.UIFigure.UserData.degrees, app.UIFigure.UserData.allWashers, app.UIFigure.UserData.tg);
%                 app.UIFigure.UserData.tg.stop;
            end
                
        end

        % Button pushed function: WashersPictureButton
        function WashersPictureButtonPushed(app, event)
            %app.UIFigure.UserData.snapShot = getSnapshotImage(app.UIFigure.UserData.cam);
            app.UIFigure.UserData.snapShot = imread('back2.jpg');
            imshow(app.UIFigure.UserData.snapShot);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BackgroundPictureButton = uibutton(app.UIFigure, 'push');
            app.BackgroundPictureButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundPictureButtonPushed, true);
            app.BackgroundPictureButton.Text = 'Background Picture';
            app.WashersPictureButton = uibutton(app.UIFigure, 'push');
            app.WashersPictureButton.ButtonPushedFcn = createCallbackFcn(app, @WashersPictureButtonPushed, true);
            app.WashersPictureButton.Text = 'Washers Picture';
            app.FindWashersButton = uibutton(app.UIFigure, 'push');
            app.FindWashersButton.ButtonPushedFcn = createCallbackFcn(app, @FindWashersButtonPushed, true);
            app.FindWashersButton.Text = 'Find Washers';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable_2.RowName = {};
            app.StartProcessButton = uibutton(app.UIFigure, 'push');
            app.StartProcessButton.Text = 'Start Process';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end